classdef jointStateController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Joint3Label            matlab.ui.control.Label
        Joint2Label            matlab.ui.control.Label
        Joint1Label            matlab.ui.control.Label
        SpreadLabel            matlab.ui.control.Label
        MiddleLabel            matlab.ui.control.Label
        PointerLabel           matlab.ui.control.Label
        ThumbLabel             matlab.ui.control.Label
        MiddleSpread_Slider    matlab.ui.control.Slider
        MiddleSpread_Spinner   matlab.ui.control.Spinner
        MiddleJoint1_Slider    matlab.ui.control.Slider
        MiddleJoint1_Spinner   matlab.ui.control.Spinner
        MiddleJoint2_Spinner   matlab.ui.control.Spinner
        MiddleJoint3_Spinner   matlab.ui.control.Spinner
        MiddleJoint2_Slider    matlab.ui.control.Slider
        MiddleJoint3_Slider    matlab.ui.control.Slider
        PointerJoint3_Slider   matlab.ui.control.Slider
        PointerJoint2_Slider   matlab.ui.control.Slider
        PointerJoint1_Slider   matlab.ui.control.Slider
        PointerSpread_Slider   matlab.ui.control.Slider
        PointerJoint3_Spinner  matlab.ui.control.Spinner
        PointerJoint2_Spinner  matlab.ui.control.Spinner
        PointerJoint1_Spinner  matlab.ui.control.Spinner
        PointerSpread_Spinner  matlab.ui.control.Spinner
        ThumbSpread_Spinner    matlab.ui.control.Spinner
        ThumbJoint1_Spinner    matlab.ui.control.Spinner
        ThumbJoint2_Spinner    matlab.ui.control.Spinner
        ThumbJoint3_Spinner    matlab.ui.control.Spinner
        HomeButton             matlab.ui.control.Button
        RandomiseButton        matlab.ui.control.Button
        ThumbSpread_Slider     matlab.ui.control.Slider
        ThumbJoint1_Slider     matlab.ui.control.Slider
        ThumbJoint2_Slider     matlab.ui.control.Slider
        ThumbJoint3_Slider     matlab.ui.control.Slider
    end

    
    properties (Access = private)
        node
        pub 
        msg 
        jointPositions
        sliders
        spinners
    end
    
    methods (Access = private)
        
        function publishJoints(app)
            app.msg.position = app.jointPositions;
            app.msg.header.stamp = ros2time(app.node, "now");
            send(app.pub, app.msg);
        end
        
        % Fired when a slider is updated
        function jointChanged(app, slider, event)
            idx = str2double(slider.Tag);
            value = event.Value;

            updateJoint(app, idx, value);
        end
        
        % Update the UI when a joint is changed
        function updateJoint(app, idx, value)
            app.jointPositions(idx) = value;

            app.sliders(idx).Value = value;
            app.spinners(idx).Value = value;

            publishJoints(app);
        end
        
        % Fired when a spinner is updated
        function spinnerChanged(app, spinner)
            idx = find(app.spinners == spinner);
            value = spinner.Value;

            updateJoint(app, idx, value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setenv('ROS_DOMAIN_ID', '93');
            
            app.node = ros2node("matlab_node");
            app.pub = ros2publisher(app.node, "joint_states", "sensor_msgs/JointState");
            app.msg =  ros2message(app.pub);

            app.msg.name = { ...
                'thumb_joint0','thumb_joint1','thumb_joint2','thumb_joint3', ...
                'pointer_joint0','pointer_joint1','pointer_joint2','pointer_joint3', ...
                'middle_joint0','middle_joint1','middle_joint2','middle_joint3' };

            app.jointPositions = zeros(1,12);
            app.msg.position = zeros(1, 12);
            app.msg.velocity = [];
            app.msg.effort = [];

            % Connect function to all sliders
            app.sliders = [ ...
                app.ThumbSpread_Slider ...
                app.ThumbJoint1_Slider ...
                app.ThumbJoint2_Slider ...
                app.ThumbJoint3_Slider ...
                app.PointerSpread_Slider ...
                app.PointerJoint1_Slider ...
                app.PointerJoint2_Slider ...
                app.PointerJoint3_Slider ...
                app.MiddleSpread_Slider ...
                app.MiddleJoint1_Slider ...
                app.MiddleJoint2_Slider ...
                app.MiddleJoint3_Slider ];

            app.spinners = [ ...
                app.ThumbSpread_Spinner ...
                app.ThumbJoint1_Spinner ...
                app.ThumbJoint2_Spinner ...
                app.ThumbJoint3_Spinner ...
                app.PointerSpread_Spinner ...
                app.PointerJoint1_Spinner ...
                app.PointerJoint2_Spinner ...
                app.PointerJoint3_Spinner ...
                app.MiddleSpread_Spinner ...
                app.MiddleJoint1_Spinner ...
                app.MiddleJoint2_Spinner ...
                app.MiddleJoint3_Spinner ];

            for k = 1:length(app.sliders)
                app.sliders(k).ValueChangingFcn = @(src, event) jointChanged(app, src, event);
                app.spinners(k).ValueChangedFcn = @(src, event) spinnerChanged(app, src);
            end

            publishJoints(app);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.jointPositions = zeros(1,12);

            for k = 1:length(app.sliders)
                app.sliders(k).Value = 0;
                app.spinners(k).Value = 0;
            end

            publishJoints(app);
        end

        % Button pushed function: RandomiseButton
        function RandomiseButtonPushed(app, event)
            for k = 1:length(app.sliders)

                % Get slider limits
                limits = app.sliders(k).Limits;
                minVal = limits(1);
                maxVal = limits(2);
        
                % Generate random value inside limits
                value = minVal + (maxVal - minVal) * rand;
        
                % Update internal state
                app.jointPositions(k) = value;
        
                % Sync UI
                app.sliders(k).Value = value;
                app.spinners(k).Value = value;
        
            end
        
            publishJoints(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ThumbJoint3_Slider = uislider(app.UIFigure);
            app.ThumbJoint3_Slider.Limits = [0 1.5];
            app.ThumbJoint3_Slider.Tag = '4';
            app.ThumbJoint2_Slider = uislider(app.UIFigure);
            app.ThumbJoint2_Slider.Limits = [0 1.5];
            app.ThumbJoint2_Slider.MajorTickLabels = {'0', '0.3', '0.6', '0.9', '1.2', '1.5'};
            app.ThumbJoint2_Slider.Tag = '3';
            app.ThumbJoint1_Slider = uislider(app.UIFigure);
            app.ThumbJoint1_Slider.Limits = [-0.2 1];
            app.ThumbJoint1_Slider.Tag = '2';
            app.ThumbSpread_Slider = uislider(app.UIFigure);
            app.ThumbSpread_Slider.Limits = [-0.1 0.1];
            app.ThumbSpread_Slider.Tag = '1';
            app.RandomiseButton = uibutton(app.UIFigure, 'push');
            app.RandomiseButton.ButtonPushedFcn = createCallbackFcn(app, @RandomiseButtonPushed, true);
            app.RandomiseButton.Text = 'Randomise';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.ThumbJoint3_Spinner = uispinner(app.UIFigure);
            app.ThumbJoint3_Spinner.Step = 0.0015;
            app.ThumbJoint3_Spinner.Limits = [0 1.5];
            app.ThumbJoint2_Spinner = uispinner(app.UIFigure);
            app.ThumbJoint2_Spinner.Step = 0.0015;
            app.ThumbJoint2_Spinner.Limits = [0 1.5];
            app.ThumbJoint1_Spinner = uispinner(app.UIFigure);
            app.ThumbJoint1_Spinner.Step = 0.0012;
            app.ThumbJoint1_Spinner.Limits = [-0.2 1];
            app.ThumbSpread_Spinner = uispinner(app.UIFigure);
            app.ThumbSpread_Spinner.Step = 0.0002;
            app.ThumbSpread_Spinner.Limits = [-0.1 0.1];
            app.PointerSpread_Spinner = uispinner(app.UIFigure);
            app.PointerSpread_Spinner.Step = 0.0002;
            app.PointerSpread_Spinner.Limits = [-0.1 0.1];
            app.PointerJoint1_Spinner = uispinner(app.UIFigure);
            app.PointerJoint1_Spinner.Step = 0.0012;
            app.PointerJoint1_Spinner.Limits = [-0.2 1];
            app.PointerJoint2_Spinner = uispinner(app.UIFigure);
            app.PointerJoint2_Spinner.Step = 0.0015;
            app.PointerJoint2_Spinner.Limits = [0 1.5];
            app.PointerJoint3_Spinner = uispinner(app.UIFigure);
            app.PointerJoint3_Spinner.Step = 0.0015;
            app.PointerJoint3_Spinner.Limits = [0 1.5];
            app.PointerSpread_Slider = uislider(app.UIFigure);
            app.PointerSpread_Slider.Limits = [-0.1 0.1];
            app.PointerSpread_Slider.Tag = '5';
            app.PointerJoint1_Slider = uislider(app.UIFigure);
            app.PointerJoint1_Slider.Limits = [-0.2 1];
            app.PointerJoint1_Slider.Tag = '6';
            app.PointerJoint2_Slider = uislider(app.UIFigure);
            app.PointerJoint2_Slider.Limits = [0 1.5];
            app.PointerJoint2_Slider.Tag = '7';
            app.PointerJoint3_Slider = uislider(app.UIFigure);
            app.PointerJoint3_Slider.Limits = [0 1.5];
            app.PointerJoint3_Slider.Tag = '8';
            app.MiddleJoint3_Slider = uislider(app.UIFigure);
            app.MiddleJoint3_Slider.Limits = [0 1.5];
            app.MiddleJoint3_Slider.Tag = '12';
            app.MiddleJoint2_Slider = uislider(app.UIFigure);
            app.MiddleJoint2_Slider.Limits = [0 1.5];
            app.MiddleJoint2_Slider.Tag = '11';
            app.MiddleJoint3_Spinner = uispinner(app.UIFigure);
            app.MiddleJoint3_Spinner.Step = 0.0015;
            app.MiddleJoint3_Spinner.Limits = [0 1.5];
            app.MiddleJoint2_Spinner = uispinner(app.UIFigure);
            app.MiddleJoint2_Spinner.Step = 0.0015;
            app.MiddleJoint2_Spinner.Limits = [0 1.5];
            app.MiddleJoint1_Spinner = uispinner(app.UIFigure);
            app.MiddleJoint1_Spinner.Step = 0.0012;
            app.MiddleJoint1_Spinner.Limits = [-0.2 1];
            app.MiddleJoint1_Slider = uislider(app.UIFigure);
            app.MiddleJoint1_Slider.Limits = [-0.2 1];
            app.MiddleJoint1_Slider.Tag = '10';
            app.MiddleSpread_Spinner = uispinner(app.UIFigure);
            app.MiddleSpread_Spinner.Step = 0.0002;
            app.MiddleSpread_Spinner.Limits = [-0.1 0.1];
            app.MiddleSpread_Slider = uislider(app.UIFigure);
            app.MiddleSpread_Slider.Limits = [-0.1 0.1];
            app.MiddleSpread_Slider.Tag = '9';
            app.ThumbLabel = uilabel(app.UIFigure);
            app.ThumbLabel.Text = 'Thumb';
            app.PointerLabel = uilabel(app.UIFigure);
            app.PointerLabel.Text = 'Pointer';
            app.MiddleLabel = uilabel(app.UIFigure);
            app.MiddleLabel.Text = 'Middle';
            app.SpreadLabel = uilabel(app.UIFigure);
            app.SpreadLabel.Text = 'Spread';
            app.Joint1Label = uilabel(app.UIFigure);
            app.Joint1Label.Text = 'Joint 1';
            app.Joint2Label = uilabel(app.UIFigure);
            app.Joint2Label.Text = 'Joint 2';
            app.Joint3Label = uilabel(app.UIFigure);
            app.Joint3Label.Text = 'Joint 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = jointStateController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end